classdef TableGraphEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        bgEditMode           matlab.ui.container.ButtonGroup
        Button3              matlab.ui.control.RadioButton
        Button2              matlab.ui.control.RadioButton
        rbModeXY             matlab.ui.control.RadioButton
        btnSave              matlab.ui.control.Button
        ddPlotType           matlab.ui.control.DropDown
        DropDownLabel        matlab.ui.control.Label
        ddVariable           matlab.ui.control.DropDown
        SelectvariableLabel  matlab.ui.control.Label
        tblData              matlab.ui.control.Table
        axPlot               matlab.ui.control.UIAxes
    end

    
properties (SetAccess = public)
    % === Данные ===
    originalData        % Исходные данные (полная матрица)
    currentData         % Текущие редактируемые данные
    selectedVariable    % Выбранная переменная из workspace
    editMode = 'XY'     % Режим редактирования: 'XY'
    currentPlotType = 'columns' % 'columns' или 'rows'
    
    % === Выбор части данных ===
    selectedColumns = [] % Индексы выбранных столбцов
    selectedRows = []    % Индексы выбранных строк
    
    % === Метки ===
    rowLabels = {}      % Метки строк
    columnLabels = {}   % Метки столбцов
    
    % === Перетаскивание ===
    selectedPoint = []  % Индекс выбранной точки [curveIndex, pointIndex]
    isDragging = false  % Флаг активного перетаскивания
    dragStartPosition = [] % Начальная позиция перетаскивания [x, y]
    
    % === Состояние приложения ===
    isUpdating = false  % Флаг обновления (предотвращает циклические обновления)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                 % STARTUPFCN Выполняется при запуске приложения
            %   Инициализирует dropdown переменных из workspace
            %   Вызывает helper функцию updateVariableDropdown из helpers/
            try
                % Проверить, что dropdown существует (используем isprop для App Designer)
                % В App Designer компоненты создаются как свойства, не как поля
                if ~isprop(app, 'ddVariable')
                    fprintf('Предупреждение: свойство ddVariable не найдено\n');
                    fprintf('Диагностика: проверяем доступные свойства...\n');
                    
                    % Диагностика: показать доступные свойства, содержащие 'Variable'
                    props = properties(app);
                    matchingProps = props(contains(props, 'Variable', 'IgnoreCase', true) | ...
                                        contains(props, 'dd', 'IgnoreCase', true) | ...
                                        contains(props, 'Dropdown', 'IgnoreCase', true));
                    if ~isempty(matchingProps)
                        fprintf('Найдены похожие свойства: %s\n', strjoin(matchingProps, ', '));
                    else
                        fprintf('Похожие свойства не найдены. Убедитесь, что компонент создан в Design View\n');
                    end
                    return;
                end
                
                % Проверить, что объект валиден
                if ~isvalid(app.ddVariable)
                    fprintf('Предупреждение: ddVariable не валиден\n');
                    return;
                end
                
                % Назначить callback программно (всегда, для надежности)
                fprintf('Назначение callback ddVariableValueChanged...\n');
                try
                    % Используем createCallbackFcn для правильной работы с private методами
                    app.ddVariable.ValueChangedFcn = createCallbackFcn(app, @ddVariableValueChanged, true);
                    fprintf('✓ Callback назначен через createCallbackFcn\n');
                catch ME
                    fprintf('✗ Ошибка назначения callback: %s\n', ME.message);
                    fprintf('Стек ошибки:\n');
                    for i = 1:length(ME.stack)
                        fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                    end
                end
                
                % Проверить доступность helper функции и добавить путь, если нужно
                scriptPath = fileparts(mfilename('fullpath'));
                helpersPath = fullfile(scriptPath, 'helpers');
                
                if exist(helpersPath, 'dir') && ~contains(path, helpersPath)
                    addpath(helpersPath);
                    fprintf('✓ Путь helpers/ добавлен\n');
                end
                
                % Обновить список переменных
                if exist('updateVariableDropdown', 'file') == 2
                    updateVariableDropdown(app);
                else
                    fprintf('⚠ Функция updateVariableDropdown не найдена в path\n');
                end
                
                fprintf('✓ Инициализация завершена успешно\n');
                
            catch ME
                fprintf('Ошибка в startupFcn: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка инициализации: %s', ME.message), ...
                        'Ошибка запуска', ...
                        'Icon', 'error');
                end
            end
            
        end

        % Value changed function: ddVariable
        function ddVariableValueChanged(app, event)
           % DDVARIABLEVALUECHANGED Обработчик выбора переменной
            %   Загружает данные из workspace при выборе переменной
            %   Вызывает helper функцию loadVariableFromWorkspace из helpers/
            
            fprintf('ddVariableValueChanged вызван\n');  % Отладочный вывод
            
            try
                % Получить выбранную переменную
                % Если используется ItemsData, то Value будет содержать значение из ItemsData
                % Иначе - строку из Items
                varName = app.ddVariable.Value;
                fprintf('Выбранное значение: %s (тип: %s)\n', ...
                    mat2str(varName), class(varName));
                
                % Преобразовать в строку, если это не строка
                if isnumeric(varName)
                    varName = '';
                elseif ischar(varName)
                    varName = varName;
                elseif isstring(varName)
                    varName = char(varName);
                else
                    varName = '';
                end
                
                fprintf('Обработанное значение: "%s"\n', varName);
                
                % Проверить, что выбрана валидная переменная
                if isempty(varName) || strcmp(varName, 'Select variable...') || ...
                   strcmp(varName, 'Нет числовых переменных') || strcmp(varName, '')
                    fprintf('Пропуск: значение не валидно\n');
                    return;
                end
                
                fprintf('Загрузка переменной: %s\n', varName);
                
                % Добавить путь к helpers, если нужно
                scriptPath = fileparts(mfilename('fullpath'));
                helpersPath = fullfile(scriptPath, 'helpers');
                if exist(helpersPath, 'dir') && ~contains(path, helpersPath)
                    addpath(helpersPath);
                    fprintf('✓ Путь helpers/ добавлен: %s\n', helpersPath);
                end
                
                % Проверить доступность helper функции
                funcExists = exist('loadVariableFromWorkspace', 'file');
                fprintf('Проверка функции loadVariableFromWorkspace: %d (2=файл, 5=встроенная)\n', funcExists);
                
                if funcExists == 2
                    fprintf('Вызов loadVariableFromWorkspace...\n');
                    try
                        % Загрузить переменную (helper функция сделает валидацию)
                        loadVariableFromWorkspace(app, varName);
                        fprintf('✓ loadVariableFromWorkspace завершен\n');
                    catch ME
                        fprintf('✗ Ошибка в loadVariableFromWorkspace: %s\n', ME.message);
                        fprintf('Стек ошибки:\n');
                        for i = 1:length(ME.stack)
                            fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                        end
                        rethrow(ME);
                    end
                else
                    fprintf('✗ Функция loadVariableFromWorkspace НЕ найдена в path!\n');
                    fprintf('  Путь helpers: %s\n', helpersPath);
                    fprintf('  Директория существует: %d\n', exist(helpersPath, 'dir'));
                    if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                        uialert(app.UIFigure, ...
                            sprintf('Helper функции не найдены. Путь: %s', helpersPath), ...
                            'Ошибка загрузки', ...
                            'Icon', 'error');
                    end
                end
                
            catch ME
                fprintf('Ошибка в ddVariableValueChanged: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка загрузки данных: %s', ME.message), ...
                        'Ошибка загрузки', ...
                        'Icon', 'error');
                end
            end

        end

        % Value changed function: ddPlotType
        function ddPlotTypeValueChanged(app, event)
             % DDPLOTTYPEVALUECHANGED Обработчик изменения типа графика
            %   Обновляет режим построения графика и перестраивает график
            %   Также обновляет таблицу для корректного отображения меток
            %
            %   ВАЖНО: Этот callback должен быть назначен в Design View:
            %   1. Выберите компонент ddPlotType в Design View
            %   2. В Property Inspector найдите "ValueChangedFcn"
            %   3. Установите: @app.ddPlotTypeValueChanged
            %   4. Сохраните файл
            
            fprintf('ddPlotTypeValueChanged вызван\n');  % Отладочный вывод
            
            try
                % Получить выбранное значение
                selectedValue = app.ddPlotType.Value;
                fprintf('Выбранное значение: %s (тип: %s)\n', ...
                    mat2str(selectedValue), class(selectedValue));
                
                % Преобразовать значение dropdown в режим ('columns' или 'rows')
                % Предполагаем, что Items содержат: {'By Columns', 'By Rows'}
                % или {'columns', 'rows'}
                if ischar(selectedValue) || isstring(selectedValue)
                    selectedValue = char(selectedValue);
                    if contains(selectedValue, 'Column', 'IgnoreCase', true) || ...
                       strcmp(selectedValue, 'columns')
                        plotType = 'columns';
                    elseif contains(selectedValue, 'Row', 'IgnoreCase', true) || ...
                           strcmp(selectedValue, 'rows')
                        plotType = 'rows';
                    else
                        plotType = 'columns';  % По умолчанию
                    end
                else
                    plotType = 'columns';  % По умолчанию
                end
                
                fprintf('Установка режима: %s\n', plotType);
                
                % Обновить тип графика (безопасно)
                if isprop(app, 'currentPlotType')
                    try
                        app.currentPlotType = plotType;
                        fprintf('✓ currentPlotType установлен: %s\n', plotType);
                    catch ME
                        fprintf('Предупреждение: не удалось установить currentPlotType: %s\n', ME.message);
                        % Попробовать через UserData
                        if ~isfield(app.UIFigure.UserData, 'appData')
                            app.UIFigure.UserData.appData = struct();
                        end
                        app.UIFigure.UserData.appData.currentPlotType = plotType;
                        fprintf('currentPlotType сохранен в UserData\n');
                    end
                else
                    % Сохранить в UserData, если свойство не существует
                    if ~isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData = struct();
                    end
                    app.UIFigure.UserData.appData.currentPlotType = plotType;
                    fprintf('currentPlotType сохранен в UserData (свойство не существует)\n');
                end
                
                % Обновить таблицу с новыми метками (важно для корректного отображения)
                if exist('updateTableWithData', 'file') == 2
                    fprintf('Обновление таблицы...\n');
                    updateTableWithData(app);
                    fprintf('✓ Таблица обновлена\n');
                else
                    fprintf('⚠ Функция updateTableWithData не найдена\n');
                end
                
                % Обновить график (вызов helper функции)
                if exist('updateGraph', 'file') == 2
                    fprintf('Обновление графика...\n');
                    updateGraph(app);
                    fprintf('✓ График обновлен\n');
                else
                    fprintf('⚠ Функция updateGraph не найдена\n');
                end
                
            catch ME
                fprintf('Ошибка в ddPlotTypeValueChanged: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка изменения режима графика: %s', ME.message), ...
                        'Ошибка', ...
                        'Icon', 'error');
                end
            end
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
        % BTNSAVEBUTTONPUSHED Обработчик нажатия кнопки сохранения
            %   Сохраняет данные в workspace с подтверждением
            %   Поддерживает как прямые переменные, так и переменные из структур
            %   Вызывает helper функцию saveVariableToWorkspace из helpers/
            %
            %   ВАЖНО: Этот callback должен быть назначен в Design View:
            %   1. Выберите компонент btnSave в Design View
            %   2. В Property Inspector найдите "ButtonPushedFcn"
            %   3. Установите: @app.btnSaveButtonPushed
            %   4. Сохраните файл
            
            fprintf('btnSaveButtonPushed вызван\n');  % Отладочный вывод
            
            try
                % Получить данные для сохранения (безопасно)
                currentData = [];
                selectedVariable = '';
                
                % Попробовать получить currentData
                if isprop(app, 'currentData')
                    currentData = app.currentData;
                elseif isfield(app.UIFigure.UserData, 'appData') && ...
                       isfield(app.UIFigure.UserData.appData, 'currentData')
                    currentData = app.UIFigure.UserData.appData.currentData;
                end
                
                % Попробовать получить selectedVariable
                if isprop(app, 'selectedVariable')
                    selectedVariable = app.selectedVariable;
                elseif isfield(app.UIFigure.UserData, 'appData') && ...
                       isfield(app.UIFigure.UserData.appData, 'selectedVariable')
                    selectedVariable = app.UIFigure.UserData.appData.selectedVariable;
                end
                
                % Проверить, что данные загружены
                if isempty(currentData) || isempty(selectedVariable)
                    uialert(app.UIFigure, ...
                        'Нет данных для сохранения. Сначала загрузите переменную.', ...
                        'Ошибка сохранения', ...
                        'Icon', 'warning');
                    return;
                end
                
                % Показать диалог подтверждения
                message = sprintf('Перезаписать переменную "%s" в workspace?', ...
                    selectedVariable);
                
                selection = uiconfirm(app.UIFigure, ...
                    message, ...
                    'Подтверждение сохранения', ...
                    'Options', {'Да', 'Нет'}, ...
                    'DefaultOption', 'Нет', ...
                    'CancelOption', 'Нет', ...
                    'Icon', 'question');
                
                % Проверить ответ
                if strcmp(selection, 'Да')
                    % Сохранить данные через helper функцию
                    % (поддерживает как прямые переменные, так и структуры)
                    saveVariableToWorkspace(app, selectedVariable, currentData);
                    
                    % Обновить originalData
                    if isprop(app, 'originalData')
                        app.originalData = currentData;
                    elseif isfield(app.UIFigure.UserData, 'appData')
                        app.UIFigure.UserData.appData.originalData = currentData;
                    end
                    
                    % Показать сообщение об успехе
                    uialert(app.UIFigure, ...
                        sprintf('Данные успешно сохранены в переменную "%s"', ...
                            selectedVariable), ...
                        'Сохранение завершено', ...
                        'Icon', 'success');
                    
                    fprintf('✓ Данные сохранены успешно\n');
                else
                    fprintf('Сохранение отменено пользователем\n');
                end
                
            catch ME
                fprintf('Ошибка в btnSaveButtonPushed: %s\n', ME.message);
                fprintf('Стек ошибки:\n');
                for i = 1:length(ME.stack)
                    fprintf('  %s (line %d)\n', ME.stack(i).name, ME.stack(i).line);
                end
                
                if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                    uialert(app.UIFigure, ...
                        sprintf('Ошибка сохранения: %s', ME.message), ...
                        'Ошибка сохранения', ...
                        'Icon', 'error');
                end
            end         
        end

        % Cell edit callback: tblData
        function tblDataCellEdit(app, event)
   % TBLDATACELLEDIT Callback редактирования ячейки таблицы
    %   Обновляет данные и график при изменении таблицы
    
    try
        % Предотвратить циклические обновления
        if app.isUpdating
            return;
        end
        
        app.isUpdating = true;
        
        % Получить новые данные из таблицы
        newData = app.tblData.Data;
        
        % Проверить, что данные числовые
        if ~isnumeric(newData)
            uialert(app.UIFigure, 'Данные должны быть числовыми', 'Ошибка');
            app.tblData.Data = app.currentData; % Восстановить старые данные
            app.isUpdating = false;
            return;
        end
        
        % Обновить текущие данные
        app.currentData = newData;
        
        % Обновить график (вызов helper функции)
        updateGraph(app);
        
        app.isUpdating = false;
        
    catch ME
        app.isUpdating = false;
        uialert(app.UIFigure, sprintf('Ошибка редактирования: %s', ME.message), 'Ошибка');
        % Восстановить старые данные
        app.tblData.Data = app.currentData;
    end
        end

        % Cell selection callback: tblData
        function tblDataCellSelection(app, event)
   % TBLDATACELLSELECTION Callback выделения ячеек
    %   Определяет выделенные столбцы/строки и обновляет график
    
    try
        % Получить индексы выделенных ячеек
        indices = event.Indices;
        
        if isempty(indices)
            app.selectedColumns = [];
            app.selectedRows = [];
            return;
        end
        
        % Определить выделенные столбцы и строки
        selectedRows = unique(indices(:, 1));
        selectedColumns = unique(indices(:, 2));
        
        app.selectedRows = selectedRows;
        app.selectedColumns = selectedColumns;
        
        % Если выделены только столбцы или только строки, обновить график
        % (Это будет использоваться для фильтрации данных на графике)
        % Пока просто сохраняем выделение
        
    catch ME
        fprintf('Ошибка обработки выделения: %s\n', ME.message);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Table-Graph Editor';
            app.UIFigure.Tag = 'Table-Graph Editor';
            app.axPlot = uiaxes(app.UIFigure);
            title(app.axPlot, 'Title')
            xlabel(app.axPlot, 'X')
            ylabel(app.axPlot, 'Y')
            zlabel(app.axPlot, 'Z')
            app.tblData = uitable(app.UIFigure);
            app.tblData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tblData.RowName = {};
            app.tblData.ColumnEditable = true;
            app.tblData.CellEditCallback = createCallbackFcn(app, @tblDataCellEdit, true);
            app.tblData.CellSelectionCallback = createCallbackFcn(app, @tblDataCellSelection, true);
            app.SelectvariableLabel = uilabel(app.UIFigure);
            app.SelectvariableLabel.Text = 'Select variable...';
            app.ddVariable = uidropdown(app.UIFigure);
            app.ddVariable.Items = {'Select variable...'};
            app.ddVariable.ValueChangedFcn = createCallbackFcn(app, @ddVariableValueChanged, true);
            app.ddVariable.Value = 'Select variable...';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.ddPlotType = uidropdown(app.UIFigure);
            app.ddPlotType.Items = {'By Columns', 'By Rows'};
            app.ddPlotType.ValueChangedFcn = createCallbackFcn(app, @ddPlotTypeValueChanged, true);
            app.ddPlotType.Value = 'By Columns';
            app.btnSave = uibutton(app.UIFigure, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Text = 'Save to Workspace';
            app.bgEditMode = uibuttongroup(app.UIFigure);
            app.bgEditMode.Title = 'Edit Mode';
            app.rbModeXY = uiradiobutton(app.bgEditMode);
            app.rbModeXY.Text = 'XY';
            app.rbModeXY.Value = true;
            app.Button2 = uiradiobutton(app.bgEditMode);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.bgEditMode);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TableGraphEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end